classdef vid_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        UIAxes            matlab.ui.control.UIAxes
        ClickImageButton  matlab.ui.control.Button
    end


    properties (Access = private)
        vid;
        videoInputNumber = 1;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            mkdir 'SessionData'
            app.vid = videoinput('winvideo', app.videoInputNumber);
            preview(app.vid);
            src = getselectedsource(app.vid); 
            src.ExposureMode = 'off'; 
            src.FrameRateMode = 'off';
            src.ShutterMode = 'manual';
            src.Gain = 10;
            src.Gamma = 1.15;
            src.Brightness = 5.8594;
            src.Shutter = exptime;
        end

        % Button pushed function: ClickImageButton
        function ClickImageButtonPushed(app, event)
            img = getsnapshot(app.vid);
            imshow(img, 'Parent', app.UIAxes);
            imwrite(img,'SessionData\test.jpg')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ClickImageButton = uibutton(app.UIFigure, 'push');
            app.ClickImageButton.ButtonPushedFcn = createCallbackFcn(app, @ClickImageButtonPushed, true);
            app.ClickImageButton.Text = 'Click Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vid_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end